classdef RGB_Color_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ControlbySlidersLabel    matlab.ui.control.Label
        RGBColorControllerLabel  matlab.ui.control.Label
        BlueSlider               matlab.ui.control.Slider
        BlueSliderLabel          matlab.ui.control.Label
        GreenSlider              matlab.ui.control.Slider
        GreenSliderLabel         matlab.ui.control.Label
        RedSlider                matlab.ui.control.Slider
        RedSliderLabel           matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        LampLabel                matlab.ui.control.Label
        BlueManualButton         matlab.ui.control.Button
        GreenManualButton        matlab.ui.control.Button
        RedManualButton          matlab.ui.control.Button
    end

    
    %{
    EGR102HEADERCOMMENT
    Author:     Elias Najjar
    Assignment: EGR 102-008
    Changed:    10/24/2024
    
    Purpose:
    Create an app that will control a lamp on screen and the lamp on the
    arduino by moving sliders and the potentiometer
    %}

    properties (Access = private)
        a % arduino
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.a;
            app.a = arduino();
            app.Lamp.Color = [0,0,0]; % black
        end

        % Value changing function: RedSlider
        function RedSliderValueChanging(app, event)
            changingValue = event.Value;
            redFraction = changingValue / 100;
            writePWMDutyCycle(app.a, "D5", redFraction)
            app.Lamp.Color(1) = redFraction;
        end

        % Value changing function: GreenSlider
        function GreenSliderValueChanging(app, event)
            changingValue = event.Value;
            greenFraction = changingValue / 100;
            writePWMDutyCycle(app.a, "D6", greenFraction)
            app.Lamp.Color(2) = greenFraction;
        end

        % Value changing function: BlueSlider
        function BlueSliderValueChanging(app, event)
            changingValue = event.Value;
            blueFraction = changingValue / 100;
            writePWMDutyCycle(app.a, "D9", blueFraction)
            app.Lamp.Color(3) = blueFraction;
        end

        % Button pushed function: RedManualButton
        function RedManualButtonPushed(app, event)
            app.ControlbySlidersLabel.Text = "Control Manually";
            % as long as the button on the breadboard is not pressed
            while readDigitalPin(app.a, "D4") == 0
                voltage = readVoltage(app.a, "A0");
                % convert 0-5 voltage to 0-1 duty cycle
                dutyCycle = voltage / 5;

                % change color or LED and lamp in app
                writePWMDutyCycle(app.a, "D5", dutyCycle);
                app.Lamp.Color(1) = dutyCycle;

                % make slider match
                app.RedSlider.Value = dutyCycle * 100;
            end
            app.ControlbySlidersLabel.Text = "Control by Sliders";
        end

        % Button pushed function: GreenManualButton
        function GreenManualButtonPushed(app, event)
            app.ControlbySlidersLabel.Text = "Control Manually";
            % as long as the button on the breadboard is not pressed
            while readDigitalPin(app.a, "D4") == 0
                voltage = readVoltage(app.a, "A0");
                % convert 0-5 voltage to 0-1 duty cycle
                dutyCycle = voltage / 5;

                % change color or LED and lamp in app
                writePWMDutyCycle(app.a, "D6", dutyCycle);
                app.Lamp.Color(2) = dutyCycle;

                % make slider match
                app.GreenSlider.Value = dutyCycle * 100;
            end
            app.ControlbySlidersLabel.Text = "Control by Sliders";
        end

        % Button pushed function: BlueManualButton
        function BlueManualButtonPushed(app, event)
            app.ControlbySlidersLabel.Text = "Control Manually";
            % as long as the button on the breadboard is not pressed
            while readDigitalPin(app.a, "D4") == 0
                voltage = readVoltage(app.a, "A0");
                % convert 0-5 voltage to 0-1 duty cycle
                dutyCycle = voltage / 5;

                % change color or LED and lamp in app
                writePWMDutyCycle(app.a, "D9", dutyCycle);
                app.Lamp.Color(3) = dutyCycle;

                % make slider match
                app.BlueSlider.Value = dutyCycle * 100;
            end
            app.ControlbySlidersLabel.Text = "Control by Sliders";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedManualButton = uibutton(app.UIFigure, 'push');
            app.RedManualButton.ButtonPushedFcn = createCallbackFcn(app, @RedManualButtonPushed, true);
            app.RedManualButton.Text = 'Red Manual';
            app.GreenManualButton = uibutton(app.UIFigure, 'push');
            app.GreenManualButton.ButtonPushedFcn = createCallbackFcn(app, @GreenManualButtonPushed, true);
            app.GreenManualButton.Text = 'Green Manual';
            app.BlueManualButton = uibutton(app.UIFigure, 'push');
            app.BlueManualButton.ButtonPushedFcn = createCallbackFcn(app, @BlueManualButtonPushed, true);
            app.BlueManualButton.Text = 'Blue Manual';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @RedSliderValueChanging, true);
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @GreenSliderValueChanging, true);
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @BlueSliderValueChanging, true);
            app.RGBColorControllerLabel = uilabel(app.UIFigure);
            app.RGBColorControllerLabel.Text = 'RGB Color Controller';
            app.ControlbySlidersLabel = uilabel(app.UIFigure);
            app.ControlbySlidersLabel.Text = 'Control by Sliders';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGB_Color_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end