classdef ResistorIndicatorMiniProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CheckResistanceButton    matlab.ui.control.Button
        OutofToleranceLamp       matlab.ui.control.Lamp
        OutofToleranceLampLabel  matlab.ui.control.Label
        InToleranceLamp          matlab.ui.control.Lamp
        InToleranceLampLabel     matlab.ui.control.Label
        ResistanceKnob           matlab.ui.control.DiscreteKnob
        ResistanceKnobLabel      matlab.ui.control.Label
    end

    %{
    Mini Project: Resistor Indicator
    Author:     Elias Najjar
    Assignment: EGR 102-008
    Changed:    11/21/24
            
    Purpose:
      Indicate the value of a resistor using a voltage reading and
      determine whether it is within tolerance.
    %}

    properties (Access = private)
        a % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.a;
            app.a = arduino();
        end

        % Button pushed function: CheckResistanceButton
        function CheckResistanceButtonPushed(app, event)
            % Get voltage and calculate resistance
            Vin = 5;
            R2 = 10000;
            Vout = readVoltage(app.a, 'A0');
            R1 = R2 * (Vin / Vout - 1); % came from rearranging Vout = Vin * R2 / (R1 + R2)
            tolerance = 0.05; % tolerance is +/- 5% for all resistors used
            
            % Set resistance knob and setResistance variable to check
            % tolerance later. Intervals came from decision tree found
            % earlier
            if Vout < 1.70332
                app.ResistanceKnob.Value = ('47K Ohm');
                setResistance = 47000;
            elseif Vout < 3.55083
                app.ResistanceKnob.Value = ('10K Ohm');
                setResistance = 10000;
            elseif Vout < 4.70674
                app.ResistanceKnob.Value = ('1K Ohm');
                setResistance = 1000;
            else
                app.ResistanceKnob.Value = ('330 Ohm');
                setResistance = 330;
            end
            
            % indicate tolerance by removing the incorrect lamp and
            % revealing the correct lamp
            
            % if the resistance is within 5% (tolerance is 0.05)
            if R1 >= setResistance * (1-tolerance) && R1 <= setResistance * (1+tolerance)
                app.InToleranceLamp.Visible = true;
                app.OutofToleranceLamp.Visible = false;
            else % if the resistance is out of tolerance
                app.OutofToleranceLamp.Visible = true;
                app.InToleranceLamp.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResistanceKnobLabel = uilabel(app.UIFigure);
            app.ResistanceKnobLabel.Text = 'Resistance';
            app.ResistanceKnob = uiknob(app.UIFigure, 'discrete');
            app.ResistanceKnob.Items = {'330 Ohm', '1K Ohm', '10K Ohm', '47K Ohm'};
            app.ResistanceKnob.Value = '330 Ohm';
            app.InToleranceLampLabel = uilabel(app.UIFigure);
            app.InToleranceLampLabel.Text = 'In Tolerance';
            app.InToleranceLamp = uilamp(app.UIFigure);
            app.OutofToleranceLampLabel = uilabel(app.UIFigure);
            app.OutofToleranceLampLabel.Text = 'Out of Tolerance';
            app.OutofToleranceLamp = uilamp(app.UIFigure);
            app.CheckResistanceButton = uibutton(app.UIFigure, 'push');
            app.CheckResistanceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckResistanceButtonPushed, true);
            app.CheckResistanceButton.Text = 'Check Resistance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResistorIndicatorMiniProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end