classdef RollerCoasterExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        CheckforPassengersButton  matlab.ui.control.Button
        TotalRidersLabel          matlab.ui.control.Label
        GatePositionKnob          matlab.ui.control.DiscreteKnob
        GatePositionKnobLabel     matlab.ui.control.Label
        WildBlueRollerCoasterRidesLabel  matlab.ui.control.Label
    end

    %{
    Roller Coaster App
    Author:     Elias Najjar
    Assignment: EGR 102-008
    Changed:    10/17/2024

    Build an app in MATLAB that will control the servo motor using a
    photoresistor
    %}
    
    properties (Access = private)
        a;
        s1; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.a;
            clear app.s1;

            app.a = arduino();
            app.s1 = servo(app.a, 'D9', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);

            app.TotalRidersLabel.Text = ('Gate Open, Loading Can Begin!');
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            TotalNumRiders = 0;
            writePosition(app.s1, 0.5);
            app.GatePositionKnob.Value = ('Gate Open');

            while TotalNumRiders < 5
                sensor = readVoltage(app.a, 'A0'); % reads voltage of photoresistor
                if sensor <= 1
                    writePosition(app.s1, 1);
                    TotalNumRiders = TotalNumRiders + 1; % number of riders
                    app.TotalRidersLabel.Text = join([num2str(TotalNumRiders), "Passengers"]); % display the number of riders
                    app.GatePositionKnob.Value = ('Gate Closed');
                    pause(2)
                else
                    writePosition(app.s1, 0.5);
                    app.GatePositionKnob.Value = ('Gate Open');
                    pause(2)
                end
            end
            writePosition(app.s1, 0);
            app.GatePositionKnob.Value = ('Gate Locked');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.WildBlueRollerCoasterRidesLabel = uilabel(app.UIFigure);
            app.WildBlueRollerCoasterRidesLabel.Text = 'Wild Blue Roller Coaster Rides';
            app.GatePositionKnobLabel = uilabel(app.UIFigure);
            app.GatePositionKnobLabel.Text = 'Gate Position';
            app.GatePositionKnob = uiknob(app.UIFigure, 'discrete');
            app.GatePositionKnob.Items = {'Gate Closed', 'Gate Open', 'Gate Locked'};
            app.GatePositionKnob.Value = 'Gate Closed';
            app.TotalRidersLabel = uilabel(app.UIFigure);
            app.TotalRidersLabel.Text = 'Total Riders';
            app.CheckforPassengersButton = uibutton(app.UIFigure, 'push');
            app.CheckforPassengersButton.Text = 'Check for Passengers';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Roller Coaster .jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RollerCoasterExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end